classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OpenAudioFilePanel           matlab.ui.container.Panel
        OpenAudioFileButton          matlab.ui.control.Button
        NoFileLoadedLabel            matlab.ui.control.Label
        EchoParametersPanel          matlab.ui.container.Panel
        NEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        MEditFieldLabel              matlab.ui.control.Label
        MEditField                   matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        alphaEditField               matlab.ui.control.NumericEditField
        EchoButton                   matlab.ui.control.Button
        PlayAudioFileandSaveitPanel  matlab.ui.container.Panel
        InputAudioButton             matlab.ui.control.Button
        EchoAudioButton              matlab.ui.control.Button
        SaveEchoedAudioButton        matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        DomainButtonGroup            matlab.ui.container.ButtonGroup
        TimeButton                   matlab.ui.control.RadioButton
        FrequencyButton              matlab.ui.control.RadioButton
        Panel_2                      matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        DomainButtonGroup_2          matlab.ui.container.ButtonGroup
        TimeButton_2                 matlab.ui.control.RadioButton
        FrequencyButton_2            matlab.ui.control.RadioButton
    end


    properties (Access = private)
        audioFileName = 0
        audioFileAddress = 0
        audioY = 0
        audioEcho = 0
        audioFs = 0
    end


    methods (Access = private)

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.audioFileName = file;
            app.audioFileAddress = path;
            app.NoFileLoadedLabel.Text  = file;
            [y,Fs] = audioread(fullfile(app.audioFileAddress,app.audioFileName));
            app.audioY = y;
            app.audioFs = Fs;
            
            Nsamps = length(y);
            t = (1/Fs)*(1:Nsamps);
            
            cla(app.UIAxes)
            if app.TimeButton.Value 
                plot(app.UIAxes,t,y); xlabel('Time [sec]'); ylabel('Amplitude');
            elseif app.FrequencyButton.Value
                y_fft = abs(fft(y));
                y_fft = y_fft(1:Nsamps/2); 
                f = Fs*(0:Nsamps/2-1)/Nsamps;
                plot(app.UIAxes,f,y_fft); xlabel('Frequency [Hz]'); ylabel('Magnitude');
            end
        end

        % Button pushed function: InputAudioButton
        function InputAudioButtonPushed(app, event)
            sound(app.audioY,app.audioFs);
        end

        % Selection changed function: DomainButtonGroup
        function DomainButtonGroupSelectionChanged(app, event)
            Nsamps = length(app.audioY);
            t = (1/app.audioFs)*(1:Nsamps);
            
            cla(app.UIAxes)
            if app.TimeButton.Value   
                plot(app.UIAxes,t,app.audioY); xlabel('Time [sec]'); ylabel('Amplitude');
            elseif app.FrequencyButton.Value
                y_fft = abs(fft(app.audioY));
                y_fft = y_fft(1:Nsamps/2); 
                f = app.audioFs*(0:Nsamps/2-1)/Nsamps;
                plot(app.UIAxes,f,y_fft); xlabel('Frequency [Hz]'); ylabel('Magnitude');
            end
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)
            N = app.NEditField.Value;
            alpha = app.alphaEditField.Value;
            M = app.MEditField.Value;
            
            y(1:M*N) = app.audioY(1:M*N);  
            for k = 0:N
                for i=M*k+1:length(app.audioY)  
                    y(i) = app.audioY(i) + alpha^k*app.audioY(i-M*k);  
                end  
            end
            app.audioEcho = y;
            Nsamps = length(y);
            t = (1/app.audioFs)*(1:Nsamps);
            cla(app.UIAxes_2)
            if app.TimeButton_2.Value   
                plot(app.UIAxes_2,t,y); xlabel('Time [sec]'); ylabel('Amplitude');
            elseif app.FrequencyButton_2.Value
                y_fft = abs(fft(y));
                y_fft = y_fft(1:Nsamps/2); 
                f = app.audioFs*(0:Nsamps/2-1)/Nsamps;
                plot(app.UIAxes_2,f,y_fft); xlabel('Frequency [Hz]'); ylabel('Magnitude');
            end
        end

        % Selection changed function: DomainButtonGroup_2
        function DomainButtonGroup_2SelectionChanged(app, event)
            cla(app.UIAxes_2)
            Nsamps = length(app.audioEcho);
            t = (1/app.audioFs)*(1:Nsamps);
            if app.TimeButton_2.Value   
                plot(app.UIAxes_2,t,app.audioEcho); xlabel('Time [sec]'); ylabel('Amplitude');
            elseif app.FrequencyButton_2.Value
                y_fft = abs(fft(app.audioEcho));
                y_fft = y_fft(1:Nsamps/2); 
                f = app.audioFs*(0:Nsamps/2-1)/Nsamps;
                plot(app.UIAxes_2,f,y_fft); xlabel('Frequency [Hz]'); ylabel('Magnitude');
            end
        end

        % Button pushed function: EchoAudioButton
        function EchoAudioButtonPushed(app, event)
            sound(app.audioEcho,app.audioFs);
        end

        % Button pushed function: SaveEchoedAudioButton
        function SaveEchoedAudioButtonPushed(app, event)
            filename = [app.audioFileName(1:end-4), '_echoed', '.wav'];
            audiowrite(fullfile(app.audioFileAddress,filename),app.audioEcho,app.audioFs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.OpenAudioFilePanel = uipanel(app.UIFigure);
            app.OpenAudioFilePanel.Title = 'Open Audio File';
            app.OpenAudioFileButton = uibutton(app.OpenAudioFilePanel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.NoFileLoadedLabel = uilabel(app.OpenAudioFilePanel);
            app.NoFileLoadedLabel.Text = 'No File Loaded';
            app.EchoParametersPanel = uipanel(app.UIFigure);
            app.EchoParametersPanel.Title = 'Echo Parameters';
            app.NEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.NEditField.Value = 3;
            app.MEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.MEditField.ValueDisplayFormat = '%11.8g';
            app.MEditField.Value = 44100;
            app.Label = uilabel(app.EchoParametersPanel);
            app.Label.Text = 'α';
            app.alphaEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.alphaEditField.Value = 0.7;
            app.EchoButton = uibutton(app.EchoParametersPanel, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.PlayAudioFileandSaveitPanel = uipanel(app.UIFigure);
            app.PlayAudioFileandSaveitPanel.Title = 'Play Audio File and Save it';
            app.InputAudioButton = uibutton(app.PlayAudioFileandSaveitPanel, 'push');
            app.InputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputAudioButtonPushed, true);
            app.InputAudioButton.Text = 'Input Audio';
            app.EchoAudioButton = uibutton(app.PlayAudioFileandSaveitPanel, 'push');
            app.EchoAudioButton.ButtonPushedFcn = createCallbackFcn(app, @EchoAudioButtonPushed, true);
            app.EchoAudioButton.Text = 'Echo Audio';
            app.SaveEchoedAudioButton = uibutton(app.PlayAudioFileandSaveitPanel, 'push');
            app.SaveEchoedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEchoedAudioButtonPushed, true);
            app.SaveEchoedAudioButton.Text = 'Save Echoed Audio';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Audio Signal');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.DomainButtonGroup = uibuttongroup(app.Panel);
            app.DomainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DomainButtonGroupSelectionChanged, true);
            app.DomainButtonGroup.Title = 'Domain';
            app.TimeButton = uiradiobutton(app.DomainButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.DomainButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, 'Audio Signal with Two-Sided Echos');
            xlabel(app.UIAxes_2, 'X');
            ylabel(app.UIAxes_2, 'Y');
            app.DomainButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.DomainButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DomainButtonGroup_2SelectionChanged, true);
            app.DomainButtonGroup_2.Title = 'Domain';
            app.TimeButton_2 = uiradiobutton(app.DomainButtonGroup_2);
            app.TimeButton_2.Text = 'Time';
            app.TimeButton_2.Value = true;
            app.FrequencyButton_2 = uiradiobutton(app.DomainButtonGroup_2);
            app.FrequencyButton_2.Text = 'Frequency';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end