classdef DSP0_MATLAB_q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotPanel                   matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        ParametersPanel             matlab.ui.container.Panel
        Sincn2N2Panel               matlab.ui.container.Panel
        NEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        UpsamplingPanel             matlab.ui.container.Panel
        LEditFieldLabel             matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        FilterCutoffFrequencyPanel  matlab.ui.container.Panel
        wEditFieldLabel             matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        DownsamplingPanel           matlab.ui.container.Panel
        MEditFieldLabel             matlab.ui.control.Label
        MEditField                  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        w
        L
        N
        M
        x
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.w=0;
            app.L=1;
            app.N=3;
            app.M=1;
            
            app.NEditField.Value = app.N;
            app.LEditField.Value = app.L;
            app.wEditField.Value = app.w;
            app.MEditField.Value = app.M;
            
            m=300;
            n=0:m-1;
            app.x= sinc((n-m/2)/(2*app.N)).^2;
            [H,w] = freqz(app.x,1,linspace(-3*pi,3*pi,1024));
            plot(app.UIAxes,w,abs(H))
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.w=0;
            app.L=1;
            app.M=1;
            
            app.LEditField.Value = app.L;
            app.wEditField.Value = app.w;
            app.MEditField.Value = app.M;
            
            value = app.NEditField.Value;
            app.N = value;
            m=300;
            n=0:m-1;
            app.x = sinc((n-m/2)/(2*app.N)).^2;
            [H,w] = freqz(app.x,1,linspace(-3*pi,3*pi,1024));
            plot(app.UIAxes,w,abs(H))
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            app.L = value;
            app.x = upsample(app.x,app.L);
            [H,w] = freqz(app.x,1,linspace(-3*pi,3*pi,1024));
            plot(app.UIAxes,w,abs(H))
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = value;
            app.x = downsample(app.x,app.M);
            [H,w] = freqz(app.x,1,linspace(-3*pi,3*pi,1024));
            plot(app.UIAxes,w,abs(H))
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            app.w = value;
            y1 = fir1(150, app.w, 'low');
            app.x = conv(y1,app.x);
            [H,w] = freqz(app.x,1,linspace(-3*pi,3*pi,1024));
            plot(app.UIAxes,w,abs(H))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Frequency Response');
            xlabel(app.UIAxes, 'Normalized Frequency (\times\pi rad/sample)');
            ylabel(app.UIAxes, 'Magnitude');
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.Sincn2N2Panel = uipanel(app.ParametersPanel);
            app.Sincn2N2Panel.Title = 'Sinc(n/(2N)).^2';
            app.NEditFieldLabel = uilabel(app.Sincn2N2Panel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Sincn2N2Panel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UpsamplingPanel = uipanel(app.ParametersPanel);
            app.UpsamplingPanel.Title = 'Upsampling';
            app.LEditFieldLabel = uilabel(app.UpsamplingPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UpsamplingPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.FilterCutoffFrequencyPanel = uipanel(app.ParametersPanel);
            app.FilterCutoffFrequencyPanel.Title = 'Filter Cut-off Frequency';
            app.wEditFieldLabel = uilabel(app.FilterCutoffFrequencyPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.FilterCutoffFrequencyPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.DownsamplingPanel = uipanel(app.ParametersPanel);
            app.DownsamplingPanel.Title = 'Downsampling';
            app.MEditFieldLabel = uilabel(app.DownsamplingPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.DownsamplingPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DSP0_MATLAB_q2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end